classdef SRDPVoiceLockApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        Panel4              matlab.ui.container.Panel
        TextArea            matlab.ui.control.TextArea
        TextAreaLabel       matlab.ui.control.Label
        UITable             matlab.ui.control.Table
        Panel3              matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        MFCCLabel           matlab.ui.control.Label
        Label_6             matlab.ui.control.Label
        MFCCAxes            matlab.ui.control.UIAxes
        WaveformAxes        matlab.ui.control.UIAxes
        Panel2              matlab.ui.container.Panel
        Label               matlab.ui.control.Label
        Panel               matlab.ui.container.Panel
        SampleRateLabel     matlab.ui.control.Label
        FileNameLabel       matlab.ui.control.Label
        TargetUserDropDown  matlab.ui.control.DropDown
        Label_3             matlab.ui.control.Label
        EnvDropDown         matlab.ui.control.DropDown
        Label_2             matlab.ui.control.Label
        MatchBTN            matlab.ui.control.Button
        ExtractBTN          matlab.ui.control.Button
        PlayBTN             matlab.ui.control.Button
        LoadBTN             matlab.ui.control.Button
    end

    
    properties (Access = private)
        AudioPath            % 音频文件路径
        AudioData            % 音频数据矩阵
        SampleRate           % 采样率
        FeaturesExtracted = false % 防呆锁，记录是否已提取特征
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadBTN
        function LoadBTNButtonPushed(app, event)
         % 1. 弹出对话框选择文件
            [file, path] = uigetfile({'*.wav', 'WAV Audio (*.wav)'});
            if isequal(file, 0)
                return;
            end
            
            % 2. 读取音频数据
            app.AudioPath = fullfile(path, file);
            [y, fs] = audioread(app.AudioPath);
            app.AudioData = y;
            app.SampleRate = fs;
            
            % 3. 更新左侧信息面板的 Label (炫技细节)
            app.FileNameLabel.Text = ['文件名: ', file];
            app.SampleRateLabel.Text = ['采样率: ', num2str(fs), ' Hz'];
            
            % 4. 绘制极其清爽的波形图
            plot(app.WaveformAxes, (0:length(y)-1) / fs, y, 'Color', '#2B65D9', 'LineWidth', 0.8);
            app.WaveformAxes.XLim = [0, length(y)/fs]; % 让波形紧贴边缘，去白边
            
            % 5. 重置系统的安全锁和之前的图表
            app.FeaturesExtracted = false; 
            app.TextArea.Value = '';
            app.TextArea.BackgroundColor = '#FFFFFF';
            cla(app.MFCCAxes); % 清除旧的 MFCC 图   
        end

        % Button pushed function: PlayBTN
        function PlayBTNButtonPushed(app, event)
            if isempty(app.AudioData)
                uialert(app.UIFigure, '请先导入语音文件！', '提示');
                return;
            end
            % 使用 audioplayer 播放，防止界面卡死
            player = audioplayer(app.AudioData, app.SampleRate);
            play(player);
            assignin('base', 'temp_player', player);
        end

        % Button pushed function: ExtractBTN
        function ExtractBTNButtonPushed(app, event)
            if isempty(app.AudioData)
                uialert(app.UIFigure, '请先点击【选择音频】导入语音文件！', '操作提示', 'Icon', 'warning');
                return;
            end
            
            app.UIFigure.Pointer = 'watch';
            drawnow;
            
            try
                % --- 环境混响仿真模块 ---
                envMode = app.EnvDropDown.Value;
                simulatedAudio = app.AudioData; 
                fs = app.SampleRate;
                
                if strcmp(envMode, '宿舍')
                    delay = round(0.05 * fs); 
                    simulatedAudio = app.AudioData + 0.3 * [zeros(delay,1); app.AudioData(1:end-delay)];
                elseif strcmp(envMode, '走廊')
                    delay = round(0.15 * fs); 
                    simulatedAudio = app.AudioData + 0.5 * [zeros(delay,1); app.AudioData(1:end-delay)];
                elseif strcmp(envMode, '实验室')
                    delay1 = round(0.2 * fs); 
                    delay2 = round(0.4 * fs); 
                    simulatedAudio = app.AudioData + 0.6 * [zeros(delay1,1); app.AudioData(1:end-delay1)] ...
                                                   + 0.4 * [zeros(delay2,1); app.AudioData(1:end-delay2)];
                end
                
                simulatedAudio = simulatedAudio / max(abs(simulatedAudio)); % 归一化防溢出
                
                % --- 核心：提取并绘制 MFCC ---
                coeffs = mfcc(simulatedAudio, fs);
                imagesc(app.MFCCAxes, coeffs');
                axis(app.MFCCAxes, 'xy');
                colormap(app.MFCCAxes, 'turbo'); % 使用高级的 turbo 伪彩色，完全还原设计图质感！
                
                app.FeaturesExtracted = true;
                
            catch ME
                uialert(app.UIFigure, ['特征提取失败: ', ME.message], '系统错误', 'Icon', 'error');
                app.FeaturesExtracted = false; 
            end
            
            app.UIFigure.Pointer = 'arrow';
        end

        % Button pushed function: MatchBTN
        function MatchBTNButtonPushed(app, event)
            if ~app.FeaturesExtracted
                uialert(app.UIFigure, '请先点击【提取特征】！不能跳步哦。', '操作顺序错误', 'Icon', 'warning');
                return; 
            end
            
            % 答辩演技：进度条
            d = uiprogressdlg(app.UIFigure, 'Title', '执行声纹匹配', ...
                'Message', '正在调用 VQ-LBG 模型计算量化失真距离...', 'Indeterminate', 'on');
            pause(1.5); 
            
            % 读取组长的 CSV 文件
            resultPath = fullfile(pwd, 'results', 'tables', 'lbg_vq_recognition_results.csv');
            close(d);
            
            if isfile(resultPath)
                try
                    T = readtable(resultPath, 'TextType', 'string');
                    app.UITable.Data = T; % 右侧表格直接灌入数据
                    
                    lastRow = T(end, :);
                    
                    % 组长的格式化输出
                    app.TextArea.Value = {
                        "Pred: " + lastRow.pred_speaker
                        "Decision: " + lastRow.decision
                        "Expected: " + lastRow.expected_decision
                        "Distance: " + string(lastRow.distance)
                        "Threshold: " + string(lastRow.threshold)
                    };
                    
                    % 根据结果改变文字颜色，提示更明显
                    app.TextArea.FontSize = 14;
                    app.TextArea.FontWeight = 'bold';
                    
                    decStr = lower(string(lastRow.decision));
                    if contains(decStr, 'accept') || contains(decStr, 'true') || contains(decStr, 'pass')
                        app.TextArea.FontColor = '#008000'; % 绿色通过
                    else
                        app.TextArea.FontColor = '#D9534F'; % 红色报警
                    end
                    
                catch ME
                    uialert(app.UIFigure, ['读取表格内容失败: ', ME.message], '错误', 'Icon', 'error');
                end
            else
                app.TextArea.Value = {'尚未找到 Python 结果表'};
                uialert(app.UIFigure, '找不到结果文件！', '文件缺失', 'Icon', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.LoadBTN = uibutton(app.Panel, 'push');
            app.LoadBTN.ButtonPushedFcn = createCallbackFcn(app, @LoadBTNButtonPushed, true);
            app.LoadBTN.Text = '选择音频';
            app.PlayBTN = uibutton(app.Panel, 'push');
            app.PlayBTN.ButtonPushedFcn = createCallbackFcn(app, @PlayBTNButtonPushed, true);
            app.PlayBTN.Text = '播放音频';
            app.ExtractBTN = uibutton(app.Panel, 'push');
            app.ExtractBTN.ButtonPushedFcn = createCallbackFcn(app, @ExtractBTNButtonPushed, true);
            app.ExtractBTN.Text = '特征提取';
            app.MatchBTN = uibutton(app.Panel, 'push');
            app.MatchBTN.ButtonPushedFcn = createCallbackFcn(app, @MatchBTNButtonPushed, true);
            app.MatchBTN.Text = '运行识别';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '环境模式';
            app.EnvDropDown = uidropdown(app.Panel);
            app.EnvDropDown.Items = {'纯净', '宿舍', '走廊', '实验室'};
            app.EnvDropDown.Value = '纯净';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '当前用户';
            app.TargetUserDropDown = uidropdown(app.Panel);
            app.TargetUserDropDown.Items = {'S01', 'S02', 'S03', 'S04'};
            app.TargetUserDropDown.Value = 'S01';
            app.FileNameLabel = uilabel(app.Panel);
            app.FileNameLabel.Text = '文件名:';
            app.SampleRateLabel = uilabel(app.Panel);
            app.SampleRateLabel.Text = '采样率:';
            app.Panel2 = uipanel(app.GridLayout);
            app.Panel2.Title = 'Panel2';
            app.Label = uilabel(app.Panel2);
            app.Label.Text = '轻量化声音锁--声纹认证原型系统';
            app.Panel3 = uipanel(app.GridLayout);
            app.Panel3.Title = 'Panel3';
            app.GridLayout2 = uigridlayout(app.Panel3);
            app.WaveformAxes = uiaxes(app.GridLayout2);
            xlabel(app.WaveformAxes, 'Time(s)')
            ylabel(app.WaveformAxes, 'Amliptude')
            zlabel(app.WaveformAxes, 'Z')
            app.MFCCAxes = uiaxes(app.GridLayout2);
            xlabel(app.MFCCAxes, 'Frame Index')
            ylabel(app.MFCCAxes, 'MFCC Coefficient Index')
            zlabel(app.MFCCAxes, 'Z')
            app.Label_6 = uilabel(app.GridLayout2);
            app.Label_6.Text = '语音波形';
            app.MFCCLabel = uilabel(app.GridLayout2);
            app.MFCCLabel.Text = 'MFCC特征';
            app.Panel4 = uipanel(app.GridLayout);
            app.Panel4.Title = 'Panel4';
            app.UITable = uitable(app.Panel4);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.TextAreaLabel = uilabel(app.Panel4);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.Panel4);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SRDPVoiceLockApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end